classdef DatViewer_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Dat_Viewer                 matlab.ui.Figure
        FigurePanelControl         matlab.ui.container.Panel
        VerticalCursorButton       matlab.ui.control.StateButton
        DataManipulationPanel      matlab.ui.container.Panel
        ScaleFactorButton          matlab.ui.control.StateButton
        Degree2RadianButton        matlab.ui.control.StateButton
        Radian2DegreeButton        matlab.ui.control.StateButton
        SourceCBiasEditField       matlab.ui.control.NumericEditField
        SourceCBiasEditFieldLabel  matlab.ui.control.Label
        SourceDBiasEditField       matlab.ui.control.NumericEditField
        SourceDBiasEditFieldLabel  matlab.ui.control.Label
        SourceBBiasEditField       matlab.ui.control.NumericEditField
        SourceBBiasEditFieldLabel  matlab.ui.control.Label
        SourceABiasEditField       matlab.ui.control.NumericEditField
        SourceABiasEditFieldLabel  matlab.ui.control.Label
        ClearConversionButton      matlab.ui.control.Button
        Panel2                     matlab.ui.container.Panel
        VariableList               matlab.ui.control.ListBox
        VariableListSearch         matlab.ui.control.EditField
        SearchEditFieldLabel       matlab.ui.control.Label
        VariableListDropdown       matlab.ui.control.DropDown
        VariablesFromLabel         matlab.ui.control.Label
        Panel                      matlab.ui.container.Panel
        PanelNumberSelection       matlab.ui.control.DropDown
        NumberPanelsDropDownLabel  matlab.ui.control.Label
        PlotButton                 matlab.ui.control.Button
        SourceDLabel               matlab.ui.control.Label
        SourceCLabel               matlab.ui.control.Label
        SourceBLabel               matlab.ui.control.Label
        SourceALabel               matlab.ui.control.Label
        SourceD                    matlab.ui.control.Button
        SourceC                    matlab.ui.control.Button
        SourceB                    matlab.ui.control.Button
        SourceA                    matlab.ui.control.Button
        TabGroup                   matlab.ui.container.TabGroup
        Grid1_12                   matlab.ui.container.Tab
        Grid_12                    matlab.ui.control.Table
        Grid_11                    matlab.ui.control.Table
        Grid_10                    matlab.ui.control.Table
        Grid_9                     matlab.ui.control.Table
        Grid_8                     matlab.ui.control.Table
        Grid_7                     matlab.ui.control.Table
        Grid_6                     matlab.ui.control.Table
        Grid_5                     matlab.ui.control.Table
        Grid_4                     matlab.ui.control.Table
        Grid_3                     matlab.ui.control.Table
        Grid_2                     matlab.ui.control.Table
        Grid_1                     matlab.ui.control.Table
        Grid13_25                  matlab.ui.container.Tab
        Grid_24                    matlab.ui.control.Table
        Grid_23                    matlab.ui.control.Table
        Grid_22                    matlab.ui.control.Table
        Grid_21                    matlab.ui.control.Table
        Grid_20                    matlab.ui.control.Table
        Grid_19                    matlab.ui.control.Table
        Grid_18                    matlab.ui.control.Table
        Grid_17                    matlab.ui.control.Table
        Grid_16                    matlab.ui.control.Table
        Grid_15                    matlab.ui.control.Table
        Grid_14                    matlab.ui.control.Table
        Grid_13                    matlab.ui.control.Table
    end

    
    properties (Constant = true, Access = private)
        NumberGrids = 24 % Number of available Grid
        sourceNames = ["SourceA", "SourceB", "SourceC", "SourceD"];
    end
    
    properties( Access = public )
        d DatViewer
        OriginalVariableList
    end
    
    properties (Access = private)
        LoadedSource (1,4) = false(1,4);% Description
        selectedVariable
        conversion_name string
        str_format = {'%.6e','%.6f'};
        selected_grid = [];
        selected_cells = [];
    end

    methods (Access = private)
        
        function select_data(app,source_idx)
            % Select data source. If the data source is loaded, update the
            % source's label
            app.d.import_new_source("",source_idx);
            if app.d.th(source_idx).file_name ~= ""
                app.(app.sourceNames(source_idx)+"Label").Text = app.d.th(source_idx).file_name;
                app.LoadedSource(source_idx) = true;
            end
        end
        
        function update_directory(app,source_idx)
            
            % If empty, show the variables already loaded
            if isempty(source_idx) && any(app.LoadedSource)
                source_idx = find(app.LoadedSource);
                source_idx = source_idx(1);
            end
            
            % Make sure at least there is a source
            if ~isempty(source_idx)
                app.OriginalVariableList = natsort(...
                    [app.d.th(source_idx).AvailableVariablesList(:);...
                     app.d.th(source_idx).derivedData_names]);
                app.VariableList.Items = app.OriginalVariableList;
            end

        end

        function update_grid_selection(app,grid_id,indices)
            row_ids = indices(:,1);
            n_selections = size(indices,1);
            if ~isempty(app.selected_grid) && app.selected_grid ~= grid_id
                app.("Grid_"+app.selected_grid).Selection = [];
            end
            app.selected_grid = grid_id;
            app.selected_cells = row_ids;
            % only 1 cell selection is allowed to add variable
            if n_selections == 1
                app.paste_variable(grid_id,row_ids);
            end

            % check for conversion
            app.add_conversion(grid_id,row_ids);

        end

        function paste_variable(app,grid_id,cell_id)
            
            if ~isempty(app.selectedVariable)
                var_name = [app.VariableListDropdown.Value,' - ',app.selectedVariable{1}];
                app.("Grid_"+grid_id).Data{cell_id} = var_name;
                
                % After adding the variable, reset all the selection
                app.("Grid_"+grid_id).Selection = [];
                app.selected_grid = [];
                app.selected_cells = [];
                app.selectedVariable = [];
                app.VariableList.Value = {};
            end

        end

        function add_conversion(app,grid_id,row_ids)

            if app.conversion_name ~= ""
                conversion_added = false;
                % iterate through selected rows
                for i = 1:length(row_ids)
                    % if there is a variable, add conversion factor;
                    if ~strcmp(app.("Grid_"+grid_id).Data{row_ids(i)},'')
                        conversion_added = true;
                        % check for existing conversion in the string
                        existing_conversion = regexp(app.("Grid_"+grid_id).Data{row_ids(i)},...
                                  "\[\w*(\.\d+)?((e|E)(-|+)?\d+)?\]",'match');

                        % add one if conversion doesn't exist
                        if isempty(existing_conversion)
                            var_name = [app.("Grid_"+grid_id).Data{row_ids(i)}, ' ', app.conversion_name{1}];
                            app.("Grid_"+grid_id).Data{row_ids(i)} = var_name;
                        else
                            % Otherwise, replace the conversion with a new
                            % one
                            if ~strcmp(existing_conversion,app.conversion_name{1})
                                var_name = regexprep(app.("Grid_"+grid_id).Data{row_ids(i)},...
                                  "\[\w*(\.\d+)?((e|E)(-|+)?\d+)?\]",app.conversion_name);
                                app.("Grid_"+grid_id).Data{row_ids(i)} = var_name;
                            end
                        end
                        

                    end
                end

                % After adding the conversion, reset all the selection
                if conversion_added
                    if app.Radian2DegreeButton.Value
                        app.Radian2DegreeButton.Value = false;
                    elseif app.Degree2RadianButton.Value
                        app.Degree2RadianButton.Value = false;
                    elseif app.ScaleFactorButton.Value
                        app.ScaleFactorButton.Text = 'S.F.';
                        app.ScaleFactorButton.Value = false;
                    end
                    % Reset selection and conversion
                    app.conversion_name = "";
                    app.("Grid_"+grid_id).Selection = [];
                    app.selected_grid = [];
                    app.selected_cells = [];
                end
            end
        end

        function clear_conversion(app,grid_id,row_ids)

            conversion_removed = false;
            % iterate through selected rows
            for i = 1:length(row_ids)
                % if there is a variable
                if ~strcmp(app.("Grid_"+grid_id).Data{row_ids(i)},'')

                    % check for existing conversion in the string
                    existing_conversion = regexp(app.("Grid_"+grid_id).Data{row_ids(i)},...
                              "\[\w*(\.\d+)?((e|E)(-|+)?\d+)?\]",'match');
                    if ~isempty(existing_conversion)
                        conversion_removed = true;
                        % Otherwise, replace the conversion with a new one
                        if ~strcmp(existing_conversion,app.conversion_name{1})
                            var_name = regexprep(app.("Grid_"+grid_id).Data{row_ids(i)},...
                              "\s\[\w*(\.\d+)?((e|E)(-|+)?\d+)?\]",'');
                            app.("Grid_"+grid_id).Data{row_ids(i)} = var_name;
                        end
                    end
                end
            end

            % Reset selection
            if conversion_removed
                app.("Grid_"+grid_id).Selection = [];
                app.selected_grid = [];
                app.selected_cells = [];
            end
            
        end

        function uitable_key_fcn(app,grid_id,key)

            switch key
                case "delete"
                    if isempty(app.("Grid_"+grid_id).Selection)
                        row_idx = app.selected_cells;
                    else
                        row_idx = app.("Grid_"+grid_id).Selection(:,1);
                    end
                    app.("Grid_"+grid_id).Data(row_idx) = repmat({''},length(row_idx),1);
                    app.("Grid_"+grid_id).Selection = [];
                case "backspace"
                    if isempty(app.("Grid_"+grid_id).Selection)
                        row_idx = app.selected_cells;
                    else
                        row_idx = app.("Grid_"+grid_id).Selection(:,1);
                    end
                    app.("Grid_"+grid_id).Data(row_idx) = repmat({''},length(row_idx),1);
                    app.("Grid_"+grid_id).Selection = [];
            end

        end

    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, dataManager)
            
            % Set up Rows for all Grid
            for i = 1:app.NumberGrids
                app.("Grid_"+i).Data = {'';'';'';'';'';''};
            end
        
            %-------- Initialize Data Manager
            % If dataManager is empty or not the right class, initialize one
            if isempty(dataManager) || ~isa(dataManager,'DatViewer')
                app.d = DatViewer();
            else
                % Parse the existing data
                app.d = dataManager;
                for i = 1:app.d.Nsource

                    % Load sources' infos
                    if app.d.th(i).file_name ~= ""
                        source_idx = double(app.d.th(i).source_index);
                        app.(app.sourceNames(source_idx)+"Label").Text = app.d.th(i).file_name;
                        app.LoadedSource(source_idx) = true;
                    end
                end
                app.update_directory([]);
            end
            

        end

        % Button pushed function: SourceA
        function SourceAButtonPushed(app, event)
            app.select_data(1);
            app.update_directory(1);
        end

        % Button pushed function: SourceB
        function SourceBButtonPushed(app, event)
            app.select_data(2);
            app.update_directory(2);
        end

        % Button pushed function: SourceC
        function SourceCButtonPushed(app, event)
            app.select_data(3);
            app.update_directory(3);
        end

        % Button pushed function: SourceD
        function SourceDButtonPushed(app, event)
            app.select_data(4);
            app.update_directory(4);
        end

        % Value changed function: VariableListDropdown
        function VariableListDropdownValueChanged(app, event)
            value = string(app.VariableListDropdown.Value);
            source_idx = find(contains(app.sourceNames,value));
            app.update_directory(source_idx);

            
        end

        % Close request function: Dat_Viewer
        function Dat_ViewerCloseRequest(app, event)
            delete(app)
            
        end

        % Value changing function: VariableListSearch
        function VariableListSearchValueChanging(app, event)
            changingValue = strtrim(string(event.Value));
            if changingValue == ""
                app.VariableList.Items = app.OriginalVariableList;
            else
                idx = contains(app.OriginalVariableList,changingValue);
                app.VariableList.Items = app.OriginalVariableList(idx);
            end
            
        end

        % Value changed function: VariableList
        function VariableListValueChanged(app, event)
            value = string(app.VariableList.Value);
            app.selectedVariable = value;
            if ~isempty(app.selected_cells) &&...
                    length(app.selected_cells) == 1
                app.paste_variable(app.selected_grid,app.selected_cells)
            end
        end

        % Cell selection callback: Grid_1
        function Grid_1CellSelection(app, event)
            indices = event.Indices;
            grid_id = 1;
            app.update_grid_selection(grid_id,indices);
            
        end

        % Cell selection callback: Grid_2
        function Grid_2CellSelection(app, event)
            indices = event.Indices;
            grid_id = 2;
            app.update_grid_selection(grid_id,indices);
        end

        % Cell selection callback: Grid_3
        function Grid_3CellSelection(app, event)
            indices = event.Indices;
            grid_id = 3;
            app.update_grid_selection(grid_id,indices);
        end

        % Cell selection callback: Grid_4
        function Grid_4CellSelection(app, event)
            indices = event.Indices;
            grid_id = 4;
            app.update_grid_selection(grid_id,indices);
        end

        % Cell selection callback: Grid_5
        function Grid_5CellSelection(app, event)
            indices = event.Indices;
            grid_id = 5;
            app.update_grid_selection(grid_id,indices);
        end

        % Cell selection callback: Grid_6
        function Grid_6CellSelection(app, event)
            indices = event.Indices;
            grid_id = 6;
            app.update_grid_selection(grid_id,indices);
        end

        % Key press function: Grid_1
        function Grid_1KeyPress(app, event)
            key = string(event.Key);
            grid_id = 1;
            app.uitable_key_fcn(grid_id,key);
            
        end

        % Key press function: Grid_2
        function Grid_2KeyPress(app, event)
            key = string(event.Key);
            grid_id = 2;
            app.uitable_key_fcn(grid_id,key);
            
        end

        % Key press function: Grid_3
        function Grid_3KeyPress(app, event)
            key = string(event.Key);
            grid_id = 3;
            app.uitable_key_fcn(grid_id,key);
            
        end

        % Key press function: Grid_4
        function Grid_4KeyPress(app, event)
            key = string(event.Key);
            grid_id = 4;
            app.uitable_key_fcn(grid_id,key);
            
        end

        % Key press function: Grid_5
        function Grid_5KeyPress(app, event)
            key = string(event.Key);
            grid_id = 5;
            app.uitable_key_fcn(grid_id,key);
            
        end

        % Key press function: Grid_6
        function Grid_6KeyPress(app, event)
            key = string(event.Key);
            grid_id = 6;
            app.uitable_key_fcn(grid_id,key);
            
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            app.d.datamanager_please_help();
        end

        % Value changed function: Radian2DegreeButton
        function Radian2DegreeButtonValueChanged(app, event)
            value = app.Radian2DegreeButton.Value;
            if value == true
                if app.Degree2RadianButton.Value
                    app.Degree2RadianButton.Value = false;
                elseif app.ScaleFactorButton.Value
                    app.ScaleFactorButton.Text = "S.F.";
                    app.ScaleFactorButton.Value = false;
                end
                app.conversion_name = "[R2D]";
                if ~isempty(app.selected_grid)
                    app.add_conversion(app.selected_grid,app.selected_cells)
                end
            else
                app.conversion_name = "";
            end
            disp(app.conversion_name)
        end

        % Value changed function: Degree2RadianButton
        function Degree2RadianButtonValueChanged(app, event)
            value = app.Degree2RadianButton.Value;
            if value == true
                if app.Radian2DegreeButton.Value
                    app.Radian2DegreeButton.Value = false;
                elseif app.ScaleFactorButton.Value
                    app.ScaleFactorButton.Text = "S.F.";
                    app.ScaleFactorButton.Value = false;
                end
                app.conversion_name = "[D2R]";
                if ~isempty(app.selected_grid)
                    app.add_conversion(app.selected_grid,app.selected_cells)
                end
            else
                app.conversion_name = "";
            end
            disp(app.conversion_name)
        end

        % Value changed function: ScaleFactorButton
        function ScaleFactorButtonValueChanged(app, event)
            value = app.ScaleFactorButton.Value;
            if value == true
                if app.Degree2RadianButton.Value
                    app.Degree2RadianButton.Value = false;
                elseif app.Radian2DegreeButton.Value
                    app.Radian2DegreeButton.Value = false;
                end
                answer = inputdlg('Enter a scalar scale factor:',...
                                  'Scale Factor Input',[1 30],{'1'});

                % evaluate answer
                try
                    scale_factor = eval(answer{1});
                    if abs(scale_factor) > 999.9999 || abs(scale_factor) < 0.001
                        i = 1;
                    else
                        i = 2;
                    end
                    conversion_val = string(num2str(scale_factor,app.str_format{i}));
                    app.conversion_name = "["+conversion_val+"]";
                    app.ScaleFactorButton.Text = "S.F. " + conversion_val;
                    if ~isempty(app.selected_grid)
                        app.add_conversion(app.selected_grid,app.selected_cells)
                    end
                catch
                    warning("Scale factor is not numerical");
                    app.ScaleFactorButton.Value = false;
                end

            else
                app.ScaleFactorButton.Text = "S.F.";
                app.conversion_name = "";
            end
            disp(app.conversion_name)
        end

        % Button pushed function: ClearConversionButton
        function ClearConversionButtonPushed(app, event)
            if ~isempty(app.selected_grid)
                app.clear_conversion(app.selected_grid,app.selected_cells)
            end
        end

        % Value changed function: VerticalCursorButton
        function VerticalCursorButtonValueChanged(app, event)
            value = app.VerticalCursorButton.Value;
            if value
                app.d.pt.vertical_cursor('on');
            else
                app.d.pt.vertical_cursor('off');
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Dat_Viewer = uifigure('Visible', 'off');
            app.Dat_Viewer.Name = 'DatViewer GUI';
            app.Dat_Viewer.CloseRequestFcn = createCallbackFcn(app, @Dat_ViewerCloseRequest, true);
            app.TabGroup = uitabgroup(app.Dat_Viewer);
            app.Grid1_12 = uitab(app.TabGroup);
            app.Grid1_12.Title = 'Grid 1-12';
            app.Grid_1 = uitable(app.Grid1_12);
            app.Grid_1.ColumnName = {'Grid 1'};
            app.Grid_1.RowName = {};
            app.Grid_1.CellSelectionCallback = createCallbackFcn(app, @Grid_1CellSelection, true);
            app.Grid_1.KeyPressFcn = createCallbackFcn(app, @Grid_1KeyPress, true);
            app.Grid_2 = uitable(app.Grid1_12);
            app.Grid_2.ColumnName = {'Grid 2'};
            app.Grid_2.RowName = {};
            app.Grid_2.CellSelectionCallback = createCallbackFcn(app, @Grid_2CellSelection, true);
            app.Grid_2.KeyPressFcn = createCallbackFcn(app, @Grid_2KeyPress, true);
            app.Grid_3 = uitable(app.Grid1_12);
            app.Grid_3.ColumnName = {'Grid 3'};
            app.Grid_3.RowName = {};
            app.Grid_3.CellSelectionCallback = createCallbackFcn(app, @Grid_3CellSelection, true);
            app.Grid_3.KeyPressFcn = createCallbackFcn(app, @Grid_3KeyPress, true);
            app.Grid_4 = uitable(app.Grid1_12);
            app.Grid_4.ColumnName = {'Grid 4'};
            app.Grid_4.RowName = {};
            app.Grid_4.CellSelectionCallback = createCallbackFcn(app, @Grid_4CellSelection, true);
            app.Grid_4.KeyPressFcn = createCallbackFcn(app, @Grid_4KeyPress, true);
            app.Grid_5 = uitable(app.Grid1_12);
            app.Grid_5.ColumnName = {'Grid 5'};
            app.Grid_5.RowName = {};
            app.Grid_5.CellSelectionCallback = createCallbackFcn(app, @Grid_5CellSelection, true);
            app.Grid_5.KeyPressFcn = createCallbackFcn(app, @Grid_5KeyPress, true);
            app.Grid_6 = uitable(app.Grid1_12);
            app.Grid_6.ColumnName = {'Grid 6'};
            app.Grid_6.RowName = {};
            app.Grid_6.CellSelectionCallback = createCallbackFcn(app, @Grid_6CellSelection, true);
            app.Grid_6.KeyPressFcn = createCallbackFcn(app, @Grid_6KeyPress, true);
            app.Grid_7 = uitable(app.Grid1_12);
            app.Grid_7.ColumnName = {'Grid 7'};
            app.Grid_7.RowName = {};
            app.Grid_8 = uitable(app.Grid1_12);
            app.Grid_8.ColumnName = {'Grid 8'};
            app.Grid_8.RowName = {};
            app.Grid_9 = uitable(app.Grid1_12);
            app.Grid_9.ColumnName = {'Grid 9'};
            app.Grid_9.RowName = {};
            app.Grid_10 = uitable(app.Grid1_12);
            app.Grid_10.ColumnName = {'Grid 10'};
            app.Grid_10.RowName = {};
            app.Grid_11 = uitable(app.Grid1_12);
            app.Grid_11.ColumnName = {'Grid 11'};
            app.Grid_11.RowName = {};
            app.Grid_12 = uitable(app.Grid1_12);
            app.Grid_12.ColumnName = {'Grid 12'};
            app.Grid_12.RowName = {};
            app.Grid13_25 = uitab(app.TabGroup);
            app.Grid13_25.Title = 'Grid 13-25';
            app.Grid_13 = uitable(app.Grid13_25);
            app.Grid_13.ColumnName = {'Grid 13'};
            app.Grid_13.RowName = {};
            app.Grid_14 = uitable(app.Grid13_25);
            app.Grid_14.ColumnName = {'Grid 14'};
            app.Grid_14.RowName = {};
            app.Grid_15 = uitable(app.Grid13_25);
            app.Grid_15.ColumnName = {'Grid 15'};
            app.Grid_15.RowName = {};
            app.Grid_16 = uitable(app.Grid13_25);
            app.Grid_16.ColumnName = {'Grid 16'};
            app.Grid_16.RowName = {};
            app.Grid_17 = uitable(app.Grid13_25);
            app.Grid_17.ColumnName = {'Grid 17'};
            app.Grid_17.RowName = {};
            app.Grid_18 = uitable(app.Grid13_25);
            app.Grid_18.ColumnName = {'Grid 18'};
            app.Grid_18.RowName = {};
            app.Grid_19 = uitable(app.Grid13_25);
            app.Grid_19.ColumnName = {'Grid 19'};
            app.Grid_19.RowName = {};
            app.Grid_20 = uitable(app.Grid13_25);
            app.Grid_20.ColumnName = {'Grid 20'};
            app.Grid_20.RowName = {};
            app.Grid_21 = uitable(app.Grid13_25);
            app.Grid_21.ColumnName = {'Grid 21'};
            app.Grid_21.RowName = {};
            app.Grid_22 = uitable(app.Grid13_25);
            app.Grid_22.ColumnName = {'Grid 22'};
            app.Grid_22.RowName = {};
            app.Grid_23 = uitable(app.Grid13_25);
            app.Grid_23.ColumnName = {'Grid 23'};
            app.Grid_23.RowName = {};
            app.Grid_24 = uitable(app.Grid13_25);
            app.Grid_24.ColumnName = {'Grid 24'};
            app.Grid_24.RowName = {};
            app.Panel = uipanel(app.Dat_Viewer);
            app.Panel.Title = 'Panel';
            app.SourceA = uibutton(app.Panel, 'push');
            app.SourceA.ButtonPushedFcn = createCallbackFcn(app, @SourceAButtonPushed, true);
            app.SourceA.Text = 'Source A';
            app.SourceB = uibutton(app.Panel, 'push');
            app.SourceB.ButtonPushedFcn = createCallbackFcn(app, @SourceBButtonPushed, true);
            app.SourceB.Text = 'Source B';
            app.SourceC = uibutton(app.Panel, 'push');
            app.SourceC.ButtonPushedFcn = createCallbackFcn(app, @SourceCButtonPushed, true);
            app.SourceC.Text = 'Source C';
            app.SourceD = uibutton(app.Panel, 'push');
            app.SourceD.ButtonPushedFcn = createCallbackFcn(app, @SourceDButtonPushed, true);
            app.SourceD.Text = 'Source D';
            app.SourceALabel = uilabel(app.Panel);
            app.SourceALabel.Text = '';
            app.SourceBLabel = uilabel(app.Panel);
            app.SourceBLabel.Text = '';
            app.SourceCLabel = uilabel(app.Panel);
            app.SourceCLabel.Text = '';
            app.SourceDLabel = uilabel(app.Panel);
            app.SourceDLabel.Text = '';
            app.PlotButton = uibutton(app.Panel, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.NumberPanelsDropDownLabel = uilabel(app.Panel);
            app.NumberPanelsDropDownLabel.Text = 'Number Panels';
            app.PanelNumberSelection = uidropdown(app.Panel);
            app.PanelNumberSelection.Items = {'1 Panel', '2 Panels', '3 Panels', '4 Panels', '5 Panels', '6 Panels'};
            app.PanelNumberSelection.Value = '1 Panel';
            app.Panel2 = uipanel(app.Dat_Viewer);
            app.Panel2.Title = 'Panel2';
            app.VariablesFromLabel = uilabel(app.Panel2);
            app.VariablesFromLabel.Text = 'Variables From';
            app.VariableListDropdown = uidropdown(app.Panel2);
            app.VariableListDropdown.Items = {'SourceA', 'SourceB', 'SourceC', 'SourceD'};
            app.VariableListDropdown.ValueChangedFcn = createCallbackFcn(app, @VariableListDropdownValueChanged, true);
            app.VariableListDropdown.Value = 'SourceA';
            app.SearchEditFieldLabel = uilabel(app.Panel2);
            app.SearchEditFieldLabel.Text = 'Search';
            app.VariableListSearch = uieditfield(app.Panel2, 'text');
            app.VariableListSearch.ValueChangingFcn = createCallbackFcn(app, @VariableListSearchValueChanging, true);
            app.VariableList = uilistbox(app.Panel2);
            app.VariableList.Items = {};
            app.VariableList.ValueChangedFcn = createCallbackFcn(app, @VariableListValueChanged, true);
            app.VariableList.Value = {};
            app.DataManipulationPanel = uipanel(app.Dat_Viewer);
            app.DataManipulationPanel.Title = 'Data Manipulation';
            app.ClearConversionButton = uibutton(app.DataManipulationPanel, 'push');
            app.ClearConversionButton.ButtonPushedFcn = createCallbackFcn(app, @ClearConversionButtonPushed, true);
            app.ClearConversionButton.Text = 'Clear Conversion';
            app.SourceABiasEditFieldLabel = uilabel(app.DataManipulationPanel);
            app.SourceABiasEditFieldLabel.Text = 'Source A Bias';
            app.SourceABiasEditField = uieditfield(app.DataManipulationPanel, 'numeric');
            app.SourceBBiasEditFieldLabel = uilabel(app.DataManipulationPanel);
            app.SourceBBiasEditFieldLabel.Text = 'Source B Bias';
            app.SourceBBiasEditField = uieditfield(app.DataManipulationPanel, 'numeric');
            app.SourceDBiasEditFieldLabel = uilabel(app.DataManipulationPanel);
            app.SourceDBiasEditFieldLabel.Text = 'Source D Bias';
            app.SourceDBiasEditField = uieditfield(app.DataManipulationPanel, 'numeric');
            app.SourceCBiasEditFieldLabel = uilabel(app.DataManipulationPanel);
            app.SourceCBiasEditFieldLabel.Text = 'Source C Bias';
            app.SourceCBiasEditField = uieditfield(app.DataManipulationPanel, 'numeric');
            app.Radian2DegreeButton = uibutton(app.DataManipulationPanel, 'state');
            app.Radian2DegreeButton.ValueChangedFcn = createCallbackFcn(app, @Radian2DegreeButtonValueChanged, true);
            app.Radian2DegreeButton.Text = 'Radian 2 Degree';
            app.Degree2RadianButton = uibutton(app.DataManipulationPanel, 'state');
            app.Degree2RadianButton.ValueChangedFcn = createCallbackFcn(app, @Degree2RadianButtonValueChanged, true);
            app.Degree2RadianButton.Text = 'Degree 2 Radian';
            app.ScaleFactorButton = uibutton(app.DataManipulationPanel, 'state');
            app.ScaleFactorButton.ValueChangedFcn = createCallbackFcn(app, @ScaleFactorButtonValueChanged, true);
            app.ScaleFactorButton.Text = 'S.F.';
            app.FigurePanelControl = uipanel(app.Dat_Viewer);
            app.FigurePanelControl.Title = 'Time Series Figure Control';
            app.VerticalCursorButton = uibutton(app.FigurePanelControl, 'state');
            app.VerticalCursorButton.ValueChangedFcn = createCallbackFcn(app, @VerticalCursorButtonValueChanged, true);
            app.VerticalCursorButton.Text = 'Vertical Cursor';
            % Show the figure after all components are created
            app.Dat_Viewer.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DatViewer_GUI(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Dat_Viewer)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Dat_Viewer)
        end
    end
end